classdef PAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PropellantcompositionPanel      matlab.ui.container.Panel
        SpecifyoxidizerEditFieldLabel   matlab.ui.control.Label
        SpecifyoxidizerEditField        matlab.ui.control.EditField
        SpecifyfuelEditFieldLabel       matlab.ui.control.Label
        SpecifyfuelEditField            matlab.ui.control.EditField
        DefineOFratioEditFieldLabel     matlab.ui.control.Label
        DefineOFratioEditField          matlab.ui.control.NumericEditField
        InitialconditionsPanel          matlab.ui.container.Panel
        InitialtemperatureT0KEditFieldLabel  matlab.ui.control.Label
        InitialtemperatureT0KEditField  matlab.ui.control.NumericEditField
        Initialpressurep0barEditFieldLabel  matlab.ui.control.Label
        Initialpressurep0barEditField   matlab.ui.control.NumericEditField
        SaveresultsPanel                matlab.ui.container.Panel
        SaveresultstofileCheckBox       matlab.ui.control.CheckBox
        DefinefilenameEditFieldLabel    matlab.ui.control.Label
        DefinefilenameEditField         matlab.ui.control.EditField
        UITable                         matlab.ui.control.Table
        RuncalculationsButton           matlab.ui.control.Button
        LoadexternalSolutionfilePanel   matlab.ui.container.Panel
        UseexternalsolutionfileCheckBox  matlab.ui.control.CheckBox
        DefinefilenameEditField_2Label  matlab.ui.control.Label
        DefinefilenameEditField_2       matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    
    methods (Access = public)
        
        function Input_values = CreateStruct(app)
            Input_values.oxid=app.SpecifyoxidizerEditField.Value;
            Input_values.fuel=app.SpecifyfuelEditField.Value;
            Input_values.ofratio=app.DefineOFratioEditField.Value;
            Input_values.T0=app.InitialtemperatureT0KEditField.Value;
            Input_values.p0=app.Initialpressurep0barEditField.Value;
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SaveresultsPanelSizeChanged(app, event)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
       
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Value changed function: DefineOFratioEditField
        function DefineOFratioEditFieldValueChanged(app, event)
  
        end

        % Value changed function: SpecifyfuelEditField
        function SpecifyfuelEditFieldValueChanged(app, event)
            value = app.SpecifyfuelEditField.Value;
            
        end

        % Value changed function: SpecifyoxidizerEditField
        function SpecifyoxidizerEditFieldValueChanged(app, event)
         
        end

        % Value changed function: InitialtemperatureT0KEditField
        function InitialtemperatureT0KEditFieldValueChanged(app, event)
          
        end

        % Value changed function: Initialpressurep0barEditField
        function Initialpressurep0barEditFieldValueChanged(app, event)
   
        end

        % Button pushed function: RuncalculationsButton
        function RuncalculationsButtonPushed(app, event)
            Input_values=CreateStruct(app);
            propellant=PropellantComposition(Input_values.oxid,Input_values.fuel,Input_values.ofratio,Input_values.T0,Input_values.p0);
            Simulation_results=EquilibratePropellant(propellant);
            set(app.UITable,'Data',Simulation_results);
            if  app.SaveresultstofileCheckBox.Value == 1
                filename=['C:\Users\MKret\Documents\Uni\Projekt_MKWS_2023_Michal_Kret_Bartosz_Hyzy\Results/',app.DefinefilenameEditField.Value,'.txt'];
                writetable(app.UITable.Data,filename,'FileType','text','Delimiter','tab');
            else
                
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.PropellantcompositionPanel = uipanel(app.UIFigure);
            app.PropellantcompositionPanel.Title = 'Propellant composition';
            app.SpecifyoxidizerEditFieldLabel = uilabel(app.PropellantcompositionPanel);
            app.SpecifyoxidizerEditFieldLabel.Text = 'Specify oxidizer';
            app.SpecifyoxidizerEditField = uieditfield(app.PropellantcompositionPanel, 'text');
            app.SpecifyoxidizerEditField.ValueChangedFcn = createCallbackFcn(app, @SpecifyoxidizerEditFieldValueChanged, true);
            app.SpecifyfuelEditFieldLabel = uilabel(app.PropellantcompositionPanel);
            app.SpecifyfuelEditFieldLabel.Text = 'Specify fuel';
            app.SpecifyfuelEditField = uieditfield(app.PropellantcompositionPanel, 'text');
            app.SpecifyfuelEditField.ValueChangedFcn = createCallbackFcn(app, @SpecifyfuelEditFieldValueChanged, true);
            app.DefineOFratioEditFieldLabel = uilabel(app.PropellantcompositionPanel);
            app.DefineOFratioEditFieldLabel.Text = 'Define O/F ratio';
            app.DefineOFratioEditField = uieditfield(app.PropellantcompositionPanel, 'numeric');
            app.DefineOFratioEditField.ValueChangedFcn = createCallbackFcn(app, @DefineOFratioEditFieldValueChanged, true);
            app.InitialconditionsPanel = uipanel(app.UIFigure);
            app.InitialconditionsPanel.Title = 'Initial conditions';
            app.InitialtemperatureT0KEditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.InitialtemperatureT0KEditFieldLabel.Text = 'Initial temperature T0 [K]';
            app.InitialtemperatureT0KEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.InitialtemperatureT0KEditField.ValueChangedFcn = createCallbackFcn(app, @InitialtemperatureT0KEditFieldValueChanged, true);
            app.Initialpressurep0barEditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.Initialpressurep0barEditFieldLabel.Text = 'Initial pressure p0 [bar]';
            app.Initialpressurep0barEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.Initialpressurep0barEditField.ValueChangedFcn = createCallbackFcn(app, @Initialpressurep0barEditFieldValueChanged, true);
            app.SaveresultsPanel = uipanel(app.UIFigure);
            app.SaveresultsPanel.Title = 'Save results';
            app.SaveresultstofileCheckBox = uicheckbox(app.SaveresultsPanel);
            app.SaveresultstofileCheckBox.Text = 'Save results to file';
            app.DefinefilenameEditFieldLabel = uilabel(app.SaveresultsPanel);
            app.DefinefilenameEditFieldLabel.Text = 'Define file name';
            app.DefinefilenameEditField = uieditfield(app.SaveresultsPanel, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Propellant parameter'; 'Value'; 'Unit'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.RuncalculationsButton = uibutton(app.UIFigure, 'push');
            app.RuncalculationsButton.ButtonPushedFcn = createCallbackFcn(app, @RuncalculationsButtonPushed, true);
            app.RuncalculationsButton.Text = 'Run calculations';
            app.LoadexternalSolutionfilePanel = uipanel(app.UIFigure);
            app.LoadexternalSolutionfilePanel.Title = 'Load external Solution file';
            app.UseexternalsolutionfileCheckBox = uicheckbox(app.LoadexternalSolutionfilePanel);
            app.UseexternalsolutionfileCheckBox.Text = 'Use external solution file';
            app.DefinefilenameEditField_2Label = uilabel(app.LoadexternalSolutionfilePanel);
            app.DefinefilenameEditField_2Label.Text = 'Define file name';
            app.DefinefilenameEditField_2 = uieditfield(app.LoadexternalSolutionfilePanel, 'text');
            app.BrowseButton = uibutton(app.LoadexternalSolutionfilePanel, 'push');
            app.BrowseButton.Text = 'Browse';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'sr_eng_logo_black.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PAT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end